classdef profileViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LicelProfileViewerLabel  matlab.ui.control.Label
        OpenButton               matlab.ui.control.Button
        StartTimeDropDownLabel   matlab.ui.control.Label
        StartTimeDropDown        matlab.ui.control.DropDown
        ProfileLabel             matlab.ui.control.Label
        ProfileDropDown          matlab.ui.control.DropDown
        SaveDataButton           matlab.ui.control.Button
        SavePlotButton           matlab.ui.control.Button
        HelpButton               matlab.ui.control.Button
        ExportButton             matlab.ui.control.Button
        YAXISLOGCheckBox         matlab.ui.control.CheckBox
        RCCheckBox               matlab.ui.control.CheckBox
        XAxisMinSpinnerLabel     matlab.ui.control.Label
        XAxisMinSpinner          matlab.ui.control.Spinner
        XAxisMaxSpinnerLabel     matlab.ui.control.Label
        XAxisMaxSpinner          matlab.ui.control.Spinner
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data
        indexFile
        indexData
        signal
        rc
        range
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            app.StartTimeDropDown.Items = {''};
            app.ProfileDropDown.Items = {''};
            app.data=[];
            
            [file,dir] = uigetfile('*.*','Select Licel files','MultiSelect','on');
            if ~iscell(file)
                file={file};
            end
            file = sort(file);
            filename = strcat(dir,file');

            nf = length(filename); % number of files
            for i = 1:nf
                if filename{i}(end-2:end)=='txt'
                    temp = readLicel(filename{i});
                else
                    temp = readLicelBin(filename{i});
                end
                app.data(i).Station = temp.Station;
                app.data(i).Channel = temp.Channel;
            end
            
     
            for i = 1:length(app.data)
                label = cat(2,num2str(i),' ',datestr(app.data(i).Station.StartTime));
                app.StartTimeDropDown.Items = cat(2,app.StartTimeDropDown.Items,label);
            end
            
            app.StartTimeDropDown.Enable = 'on';
            app.SaveDataButton.Enable='on';
            app.YAXISLOGCheckBox.Value=false;
            app.RCCheckBox.Value=false;
            app.YAXISLOGCheckBox.Enable='off';
            app.RCCheckBox.Enable='off';
            app.XAxisMinSpinner.Enable='off';
            app.XAxisMaxSpinner.Enable='off';
            app.XAxisMinSpinnerLabel.Enable='off';
            app.XAxisMaxSpinnerLabel.Enable='off';
            
            clear file dir filename temp
        end

        % Value changed function: StartTimeDropDown
        function StartTimeDropDownValueChanged(app, event)
            value = app.StartTimeDropDown.Value;
            
            if ~isempty(value)
                index = textscan(value, '%s');
                index = index{1}{1};
                app.indexFile = str2double(index);
                
                app.ProfileDropDown.Items = {''};        
                for i = 1:size(app.data(app.indexFile).Channel,2)
                    label = cat(2,num2str(i),' ',app.data(app.indexFile).Channel(1,i).Info,' ',app.data(app.indexFile).Channel(1,i).Descriptor);
                    app.ProfileDropDown.Items = cat(2,app.ProfileDropDown.Items,label);
                end
                app.ProfileDropDown.Enable = 'on';
            else
                app.ProfileDropDown.Items = {''};    
            end
        end

        % Value changed function: ProfileDropDown
        function ProfileDropDownValueChanged(app, event)
            value = app.ProfileDropDown.Value;
            
            if ~isempty(value)
                index = textscan(value, '%s');
                index = index{1}{1};
                app.indexData = str2double(index);
                
                title_str = cat(2,datestr(app.data(app.indexFile).Station.StartTime),' ', ...
                    app.data(app.indexFile).Channel(1,app.indexData).Info,' ', ...
                    app.data(app.indexFile).Channel(1,app.indexData).Descriptor);
                title(app.UIAxes,title_str);
                app.range = app.data(app.indexFile).Channel(1,app.indexData).Range(1:app.data(app.indexFile).Channel(1,app.indexData).Bins);
                app.signal = app.data(app.indexFile).Channel(1,app.indexData).Signal(1:app.data(app.indexFile).Channel(1,app.indexData).Bins);
                a = app.signal(:,1);
                b = app.range;
                app.rc = a.*(b.*b);
                clear a b
%                 app.UIAxes.XLim=[-inf inf];
                app.UIAxes.XLimMode = 'auto';
                plot(app.UIAxes,app.range,app.signal)
                xlabel(app.UIAxes,'Range (m)')
                if app.data(app.indexFile).Channel(1,app.indexData).Type == 0
                    ylabel(app.UIAxes,'Sig (mV)')
                elseif app.data(app.indexFile).Channel(1,app.indexData).Type == 1
                    ylabel(app.UIAxes,'Sig (MHz)')
                elseif app.data(app.indexFile).Channel(1,app.indexData).Type == 2
                    ylabel(app.UIAxes,'std (mV)')
                elseif app.data(app.indexFile).Channel(1,app.indexData).Type == 3
                    ylabel(app.UIAxes,'std (MHZ)')
                end
                app.XAxisMinSpinner.Value = app.UIAxes.XLim(1);
                app.XAxisMaxSpinner.Value = app.UIAxes.XLim(2);
                app.RCCheckBox.Value = false;
                app.SavePlotButton.Enable='on';
                app.YAXISLOGCheckBox.Enable='on';
                app.RCCheckBox.Enable='on';
                app.XAxisMinSpinner.Enable='on';
                app.XAxisMaxSpinner.Enable='on';
                app.XAxisMinSpinnerLabel.Enable='on';
                app.XAxisMaxSpinnerLabel.Enable='on';
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            help(app)
            app.HelpButton.Enable ='off';
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            data = app.data;
            uisave('data','data')
            clear data
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(app, event)
            % Create a temporary figure with axes.
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);
            % Copy all UIAxes children, take over axes limits and aspect ratio.            
            allChildren = app.UIAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.UIAxes.XLim;
            figAxes.YLim = app.UIAxes.YLim;
            figAxes.Title.String = app.UIAxes.Title.String;
            figAxes.XLabel.String = app.UIAxes.XLabel.String;
            figAxes.YLabel.String = app.UIAxes.YLabel.String;
            figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
            % Save as png and fig files.
            [filename, pathname, ~] = uiputfile({'*.png'});
            if filename~=0
                filename = strcat(pathname,filename);
                saveas(fig,filename)
            end
            % Delete the temporary figure.
            delete(fig);
        end

        % Value changed function: YAXISLOGCheckBox
        function YAXISLOGCheckBoxValueChanged(app, event)
            value = app.YAXISLOGCheckBox.Value;
            if value
                set(app.UIAxes, 'YScale', 'log')
            else
                set(app.UIAxes, 'YScale', 'linear')
            end
        end

        % Value changed function: XAxisMinSpinner
        function XAxisMinSpinnerValueChanged(app, event)
            value = app.XAxisMinSpinner.Value;
            if value<app.UIAxes.XLim(2)
                app.UIAxes.XLim(1) = value;
            else
                errordlg('X-Axis Min must be less than X-Axis Max')
                app.XAxisMinSpinner.Value=app.UIAxes.XLim(1);
            end
        end

        % Value changed function: XAxisMaxSpinner
        function XAxisMaxSpinnerValueChanged(app, event)
            value = app.XAxisMaxSpinner.Value;
            if value>app.UIAxes.XLim(1)
                app.UIAxes.XLim(2) = value;
            else
                errordlg('X-Axis Max must be greater than X-Axis Min')
                app.XAxisMaxSpinner.Value=app.UIAxes.XLim(2);
            end
        end

        % Value changed function: RCCheckBox
        function RCCheckBoxValueChanged(app, event)
            value = app.RCCheckBox.Value;
            if value
                if app.data(app.indexFile).Channel(1,app.indexData).Type == 0
                    ylabel(app.UIAxes,'RC Sig (mV m^2)')
                elseif app.data(app.indexFile).Channel(1,app.indexData).Type == 1
                    ylabel(app.UIAxes,'RC Sig (MHz m^2)')
                elseif app.data(app.indexFile).Channel(1,app.indexData).Type == 2
                    ylabel(app.UIAxes,'RC std (mV m^2)')
                elseif app.data(app.indexFile).Channel(1,app.indexData).Type == 3
                    ylabel(app.UIAxes,'RC std (MHZ m^2)')
                end
                plot(app.UIAxes,app.range,app.rc)
            else
                if app.data(app.indexFile).Channel(1,app.indexData).Type == 0
                    ylabel(app.UIAxes,'Sig (mV)')
                elseif app.data(app.indexFile).Channel(1,app.indexData).Type == 1
                    ylabel(app.UIAxes,'Sig (MHz)')
                elseif app.data(app.indexFile).Channel(1,app.indexData).Type == 2
                    ylabel(app.UIAxes,'std (mV)')
                elseif app.data(app.indexFile).Channel(1,app.indexData).Type == 3
                    ylabel(app.UIAxes,'std (MHZ)')
                end
                plot(app.UIAxes,app.range,app.signal)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LicelProfileViewerLabel = uilabel(app.UIFigure);
            app.LicelProfileViewerLabel.Text = 'Licel Profile Viewer';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.StartTimeDropDownLabel = uilabel(app.UIFigure);
            app.StartTimeDropDownLabel.Text = 'Start Time:';
            app.StartTimeDropDown = uidropdown(app.UIFigure);
            app.StartTimeDropDown.Items = {};
            app.StartTimeDropDown.ValueChangedFcn = createCallbackFcn(app, @StartTimeDropDownValueChanged, true);
            app.StartTimeDropDown.Enable = 'off';
            app.StartTimeDropDown.Value = {};
            app.ProfileLabel = uilabel(app.UIFigure);
            app.ProfileLabel.Text = 'Profile:';
            app.ProfileDropDown = uidropdown(app.UIFigure);
            app.ProfileDropDown.Items = {};
            app.ProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ProfileDropDownValueChanged, true);
            app.ProfileDropDown.Enable = 'off';
            app.ProfileDropDown.Value = {};
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Enable = 'off';
            app.SaveDataButton.Text = 'Save Data';
            app.SavePlotButton = uibutton(app.UIFigure, 'push');
            app.SavePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButtonPushed, true);
            app.SavePlotButton.Enable = 'off';
            app.SavePlotButton.Text = 'Save Plot';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.YAXISLOGCheckBox = uicheckbox(app.UIFigure);
            app.YAXISLOGCheckBox.ValueChangedFcn = createCallbackFcn(app, @YAXISLOGCheckBoxValueChanged, true);
            app.YAXISLOGCheckBox.Enable = 'off';
            app.YAXISLOGCheckBox.Text = 'Y-AXIS LOG';
            app.RCCheckBox = uicheckbox(app.UIFigure);
            app.RCCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCCheckBoxValueChanged, true);
            app.RCCheckBox.Enable = 'off';
            app.RCCheckBox.Text = 'RC ';
            app.XAxisMinSpinnerLabel = uilabel(app.UIFigure);
            app.XAxisMinSpinnerLabel.Enable = 'off';
            app.XAxisMinSpinnerLabel.Text = 'X-Axis Min';
            app.XAxisMinSpinner = uispinner(app.UIFigure);
            app.XAxisMinSpinner.ValueChangedFcn = createCallbackFcn(app, @XAxisMinSpinnerValueChanged, true);
            app.XAxisMinSpinner.Enable = 'off';
            app.XAxisMaxSpinnerLabel = uilabel(app.UIFigure);
            app.XAxisMaxSpinnerLabel.Enable = 'off';
            app.XAxisMaxSpinnerLabel.Text = 'X-Axis Max';
            app.XAxisMaxSpinner = uispinner(app.UIFigure);
            app.XAxisMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @XAxisMaxSpinnerValueChanged, true);
            app.XAxisMaxSpinner.Enable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = profileViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end